classdef Lattice_Karak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lattice_KarakLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SimpleTab                       matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        RunningLamp                     matlab.ui.control.Lamp
        EditField                       matlab.ui.control.EditField
        Switch                          matlab.ui.control.Switch
        FunctionDropDownLabel           matlab.ui.control.Label
        FunctionDropDown                matlab.ui.control.DropDown
        LevelSetKnobLabel               matlab.ui.control.Label
        LevelSetKnob                    matlab.ui.control.Knob
        ZDirectionEditField             matlab.ui.control.NumericEditField
        ZDirectionEditFieldLabel        matlab.ui.control.Label
        YDirectionEditField             matlab.ui.control.NumericEditField
        YDirectionEditFieldLabel        matlab.ui.control.Label
        XDirectionEditField             matlab.ui.control.NumericEditField
        XDirectionEditFieldLabel        matlab.ui.control.Label
        CellParametersLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        VariableDensityTab              matlab.ui.container.Tab
        DensityLabel                    matlab.ui.control.Label
        MaximumSlider                   matlab.ui.control.Slider
        MaximumSliderLabel              matlab.ui.control.Label
        MinimumSlider                   matlab.ui.control.Slider
        MinimumSliderLabel              matlab.ui.control.Label
        DirectionDropDown               matlab.ui.control.DropDown
        DirectionDropDownLabel          matlab.ui.control.Label
        ExportButton_2                  matlab.ui.control.Button
        PlotButton_2                    matlab.ui.control.Button
        RunningLamp_2                   matlab.ui.control.Lamp
        EditField_2                     matlab.ui.control.EditField
        Switch_2                        matlab.ui.control.Switch
        FunctionDropDown_2Label         matlab.ui.control.Label
        FunctionDropDown_2              matlab.ui.control.DropDown
        ZDirectionEditField_2           matlab.ui.control.NumericEditField
        ZDirectionEditField_2Label      matlab.ui.control.Label
        YDirectionEditField_2           matlab.ui.control.NumericEditField
        YDirectionEditField_2Label      matlab.ui.control.Label
        XDirectionEditField_2           matlab.ui.control.NumericEditField
        XDirectionEditField_2Label      matlab.ui.control.Label
        CellParametersLabel_2           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        CellSizeGradingTab              matlab.ui.container.Tab
        ExportButton_3                  matlab.ui.control.Button
        PlotButton_3                    matlab.ui.control.Button
        RunningLamp_3                   matlab.ui.control.Lamp
        EditField_3                     matlab.ui.control.EditField
        Switch_3                        matlab.ui.control.Switch
        FunctionDropDown_3Label         matlab.ui.control.Label
        FunctionDropDown_3              matlab.ui.control.DropDown
        LevelSetKnob_2Label             matlab.ui.control.Label
        LevelSetKnob_2                  matlab.ui.control.Knob
        EndingcellsEditField            matlab.ui.control.NumericEditField
        EndingcellsEditFieldLabel       matlab.ui.control.Label
        StartingcellsEditField          matlab.ui.control.NumericEditField
        StartingcellsEditFieldLabel     matlab.ui.control.Label
        CellParametersLabel_3           matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        HybridizationTab                matlab.ui.container.Tab
        ExportButton_4                  matlab.ui.control.Button
        PlotButton_4                    matlab.ui.control.Button
        Switch_4                        matlab.ui.control.Switch
        SigmoidFunctionkEditField       matlab.ui.control.NumericEditField
        SigmoidFunctionkEditFieldLabel  matlab.ui.control.Label
        DirectionDropDown_2             matlab.ui.control.DropDown
        DirectionDropDown_2Label        matlab.ui.control.Label
        Function2DropDown               matlab.ui.control.DropDown
        Function2DropDownLabel          matlab.ui.control.Label
        Function1DropDown               matlab.ui.control.DropDown
        Function1DropDownLabel          matlab.ui.control.Label
        LevelSetKnob_3                  matlab.ui.control.Knob
        LevelSetKnob_3Label             matlab.ui.control.Label
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        CellParameters2Label            matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        RunningLamp_4                   matlab.ui.control.Lamp
        EditField_4                     matlab.ui.control.EditField
        CellParameters1Label            matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        UIAxes_4                        matlab.ui.control.UIAxes
        HierarchicalTab                 matlab.ui.container.Tab
        LevelSetFunction2Knob           matlab.ui.control.Knob
        LevelSetFunction2KnobLabel      matlab.ui.control.Label
        LevelSetFunction1Knob           matlab.ui.control.Knob
        LevelSetFunction1KnobLabel      matlab.ui.control.Label
        ExportButton_5                  matlab.ui.control.Button
        PlotButton_5                    matlab.ui.control.Button
        Switch_5                        matlab.ui.control.Switch
        Function2DropDown_2             matlab.ui.control.DropDown
        Function2DropDown_2Label        matlab.ui.control.Label
        Function1DropDown_2             matlab.ui.control.DropDown
        Function1DropDown_2Label        matlab.ui.control.Label
        ZEditField_4Label               matlab.ui.control.Label
        ZEditField_4                    matlab.ui.control.NumericEditField
        YEditField_4Label               matlab.ui.control.Label
        YEditField_4                    matlab.ui.control.NumericEditField
        XEditField_4Label               matlab.ui.control.Label
        XEditField_4                    matlab.ui.control.NumericEditField
        CellParameters2Label_2          matlab.ui.control.Label
        ZEditField_3Label               matlab.ui.control.Label
        ZEditField_3                    matlab.ui.control.NumericEditField
        XEditField_3Label               matlab.ui.control.Label
        XEditField_3                    matlab.ui.control.NumericEditField
        YEditField_3                    matlab.ui.control.NumericEditField
        YEditField_3Label               matlab.ui.control.Label
        RunningLamp_5                   matlab.ui.control.Lamp
        EditField_5                     matlab.ui.control.EditField
        CellParameters1Label_2          matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        UIAxes_5                        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
 % Pogram for creating an STL file
 % Sven (2022). stlwrite - write ASCII or Binary STL files
 %  (https://www.mathworks.com/matlabcentral/fileexchange/20922-stlwrite-write-ascii-or-binary-stl-files)
 % MATLAB Central File Exchange.

        function stlwrite(app,filename, varargin)
                    
                                        
                    % Check valid filename path
                    path = fileparts(filename);
                    if ~isempty(path) && ~exist(path,'dir')
                        error('Directory "%s" does not exist.',path);
                    end
                    
                    % Get faces, vertices, and user-defined options for writing
                    [faces, vertices, options] = parseInputs(app,varargin{:});
                    asciiMode = strcmp( options.mode ,'ascii');
                    
                    % Create the facets
                    facets = single(vertices');
                    facets = reshape(facets(:,faces'), 3, 3, []);
                    
                    % Compute their normals
                    V1 = squeeze(facets(:,2,:) - facets(:,1,:));
                    V2 = squeeze(facets(:,3,:) - facets(:,1,:));
                    normals = V1([2 3 1],:) .* V2([3 1 2],:) - V2([2 3 1],:) .* V1([3 1 2],:);
                    clear V1 V2
                    normals = bsxfun(@times, normals, 1 ./ sqrt(sum(normals .* normals, 1)));
                    facets = cat(2, reshape(normals, 3, 1, []), facets);
                    clear normals
                    
                    % Open the file for writing
                    permissions = {'w','wb+'};
                    fid = fopen(filename, permissions{asciiMode+1});
                    if (fid == -1)
                        error('stlwrite:cannotWriteFile', 'Unable to write to %s', filename);
                    end
                    
                    % Write the file contents
                    if asciiMode
                        % Write HEADER
                        fprintf(fid,'solid %s\r\n',options.title);
                        % Write DATA
                        fprintf(fid,[...
                            'facet normal %.7E %.7E %.7E\r\n' ...
                            'outer loop\r\n' ...
                            'vertex %.7E %.7E %.7E\r\n' ...
                            'vertex %.7E %.7E %.7E\r\n' ...
                            'vertex %.7E %.7E %.7E\r\n' ...
                            'endloop\r\n' ...
                            'endfacet\r\n'], facets);
                        % Write FOOTER
                        fprintf(fid,'endsolid %s\r\n',options.title);
                        
                    else % BINARY
                        % Write HEADER
                        fprintf(fid, '%-80s', options.title);             % Title
                        fwrite(fid, size(facets, 3), 'uint32');           % Number of facets
                        % Write DATA
                        % Add one uint16(0) to the end of each facet using a typecasting trick
                        facets = reshape(typecast(facets(:), 'uint16'), 12*2, []);
                        % Set the last bit to 0 (default) or supplied RGB
                        facets(end+1,:) = options.facecolor;
                        fwrite(fid, facets, 'uint16');
                    end
                    
                    % Close the file
                    fclose(fid);
                   fprintf('',size(faces, 2));
        end
     
     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
        
        function [faces, vertices, options] = parseInputs(app,varargin)
                                % Determine input type
                                if isstruct(varargin{1}) % stlwrite('file', FVstruct, ...)
                                    if ~all(isfield(varargin{1},{'vertices','faces'}))
                                        error( 'Variable p must be a faces/vertices structure' );
                                    end
                                    faces = varargin{1}.faces;
                                    vertices = varargin{1}.vertices;
                                    options = parseOptions(app,varargin{2:end});
                                    
                                elseif isnumeric(varargin{1})
                                    firstNumInput = cellfun(@isnumeric,varargin);
                                    firstNumInput(find(~firstNumInput,1):end) = 0; % Only consider numerical input PRIOR to the first non-numeric
                                    numericInputCnt = nnz(firstNumInput);
                                    
                                    options = parseOptions(app,varargin{numericInputCnt+1:end});
                                    switch numericInputCnt
                                        case 3 % stlwrite('file', X, Y, Z, ...)
                                            % Extract the matrix Z
                                            Z = varargin{3};
                                            
                                            % Convert scalar XY to vectors
                                            ZsizeXY = fliplr(size(Z));
                                            for i = 1:2
                                                if isscalar(varargin{i})
                                                    varargin{i} = (0:ZsizeXY(i)-1) * varargin{i};
                                                end                    
                                            end
                                            
                                            % Extract X and Y
                                            if isequal(size(Z), size(varargin{1}), size(varargin{2}))
                                                % X,Y,Z were all provided as matrices
                                                [X,Y] = varargin{1:2};
                                            elseif numel(varargin{1})==ZsizeXY(1) && numel(varargin{2})==ZsizeXY(2)
                                                % Convert vector XY to meshgrid
                                                [X,Y] = meshgrid(varargin{1}, varargin{2});
                                            else
                                                error('stlwrite:badinput', 'Unable to resolve X and Y variables');
                                            end
                                            
                                            % Convert to faces/vertices
                                            if strcmp(options.triangulation,'delaunay')
                                                faces = delaunay(X,Y);
                                                vertices = [X(:) Y(:) Z(:)];
                                            else
                                                if ~exist('mesh2tri','file')
                                                    error('stlwrite:missing', '"mesh2tri" is required to convert X,Y,Z matrices to STL. It can be downloaded from:\n%s\n',...
                                                        'http://www.mathworks.com/matlabcentral/fileexchange/28327')
                                                end
                                                [faces, vertices] = mesh2tri(app,X, Y, Z, options.triangulation);
                                            end
                                            
                                        case 2 % stlwrite('file', FACES, VERTICES, ...)
                                            faces = varargin{1};
                                            vertices = varargin{2};
                                            
                                        otherwise
                                            error('stlwrite:badinput', 'Unable to resolve input types.');
                                    end
                                end
                                
                                if size(faces,2)~=3
                                    errorMsg = {
                                        sprintf('The FACES input array should hold triangular faces (N x 3), but was detected as N x %d.',size(faces,2))
                                        'The STL format is for triangulated surfaces (i.e., surfaces made from 3-sided triangles).'
                                        'The Geom3d package (https://www.mathworks.com/matlabcentral/fileexchange/24484-geom3d) contains'
                                        'a "triangulateFaces" function which can be used convert your faces into triangles.'
                                        };
                                    error('stlwrite:nonTriangles', '%s\n',errorMsg{:})
                                end
                                
                                if ~isempty(options.facecolor) % Handle colour preparation
                                    facecolor = uint16(options.facecolor);
                                    %Set the Valid Color bit (bit 15)
                                    c0 = bitshift(ones(size(faces,1),1,'uint16'),15);
                                    %Red color (10:15), Blue color (5:9), Green color (0:4)
                                    c0 = bitor(bitshift(bitand(2^6-1, facecolor(:,1)),10),c0);
                                    c0 = bitor(bitshift(bitand(2^11-1, facecolor(:,2)),5),c0);
                                    c0 = bitor(bitand(2^6-1, facecolor(:,3)),c0);
                                    options.facecolor = c0;    
                                else
                                    options.facecolor = 0;
                                end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        function options = parseOptions(app,varargin)
                IP = inputParser;
                IP.addParameter('mode', 'binary', @ischar)
                IP.addParameter('title', sprintf('Created by stlwrite.m %s',datestr(now)), @ischar);
                IP.addParameter('triangulation', 'delaunay', @ischar);
                IP.addParameter('facecolor',[], @isnumeric)
                IP.addParameter('facecolour',[], @isnumeric)
                IP.parse(varargin{:});
                options = IP.Results;
                if ~isempty(options.facecolour)
                    options.facecolor = options.facecolour;
                end
            
        end
        
        
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        function [F,V]=mesh2tri(app,X,Y,Z,tri_type)
                    % function [F,V]=mesh2tri(X,Y,Z,tri_type)
                    % 
                    % Available from http://www.mathworks.com/matlabcentral/fileexchange/28327
                    % Included here for convenience. Many thanks to Kevin Mattheus Moerman
                    % kevinmoerman@hotmail.com
                    % 15/07/2010
                    %------------------------------------------------------------------------
                    
                    [J,I]=meshgrid(1:1:size(X,2)-1,1:1:size(X,1)-1);
                    
                    switch tri_type

                        case 'f'%Forward slash
                            TRI_I=[I(:),I(:)+1,I(:)+1;  I(:),I(:),I(:)+1];
                            TRI_J=[J(:),J(:)+1,J(:);   J(:),J(:)+1,J(:)+1];
                            F = sub2ind(size(X),TRI_I,TRI_J);
                        case 'b'%Back slash
                            TRI_I=[I(:),I(:)+1,I(:);  I(:)+1,I(:)+1,I(:)];
                            TRI_J=[J(:)+1,J(:),J(:);   J(:)+1,J(:),J(:)+1];
                            F = sub2ind(size(X),TRI_I,TRI_J);
                        case 'x'%Cross
                            TRI_I=[I(:)+1,I(:);  I(:)+1,I(:)+1;  I(:),I(:)+1;    I(:),I(:)];
                            TRI_J=[J(:),J(:);    J(:)+1,J(:);    J(:)+1,J(:)+1;  J(:),J(:)+1];
                            IND=((numel(X)+1):numel(X)+prod(size(X)-1))';
                            F = sub2ind(size(X),TRI_I,TRI_J);
                            F(:,3)=repmat(IND,[4,1]);
                            Fe_I=[I(:),I(:)+1,I(:)+1,I(:)]; Fe_J=[J(:),J(:),J(:)+1,J(:)+1];
                            Fe = sub2ind(size(X),Fe_I,Fe_J);
                            Xe=mean(X(Fe),2); Ye=mean(Y(Fe),2);  Ze=mean(Z(Fe),2);
                            X=[X(:);Xe(:)]; Y=[Y(:);Ye(:)]; Z=[Z(:);Ze(:)];
                    end 
                    
                    V=[X(:),Y(:),Z(:)];
                                
        end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
           
               
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global T;  % Funtion
            
        
            app.RunningLamp.Color = [1,0,0];          
            drawnow; 
            answer = 'Please wait...';
            app.EditField.Value = answer;
            drawnow; 
       
            n1 = app.XDirectionEditField.Value;
            n2 = app.YDirectionEditField.Value;
            n3 = app.ZDirectionEditField.Value;
            
            a=2*n1*pi; % x direction parameters
            b=2*n2*pi; % y direction parameters
            g=2*n3*pi;  % z direction parametes 
            
            s=0.01;
            
            [x,y,z]=meshgrid(-0.5:s:0.5,-0.5:s:0.5,-0.5:s:0.5);
            
            
            switch app.FunctionDropDown.Value
                case 'Gyroid'
                    
                    u = (sin(a.*x).* cos(b.*y) + sin(b.*y).* cos(g.*z) + sin(g.*z).* cos(a.*x));
                    
                       name = 'Gyroid'; 
                    
                case 'Double Gyroid'
                    
                     u = 2.75*(sin(2*a.*x).*sin(g.*z).*cos(b.*y) + sin(2*b.*y).*sin(a.*x).*cos(g.*z) + sin(2*g.*z).*sin(b.*y).*cos(a.*x)) ...
      - (cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z) + cos(2*g.*z).*cos(2*a.*x));
                    
                     name = 'Double Gyroid'; 
                    
                case 'Schwarz Primitive (SP)'
                    
                    u = cos(a.*x) + cos(b.*y) + cos(g.*z); 
                   
                      name = 'Schwarz Primitive (SP)'; 
                    
                case 'Double SP'
                    
                    u = 0.5*(cos(a.*x).*cos(b.*y) + cos(b.*y).*cos(g.*z) + cos(g.*z).*cos(a.*x)) + 0.2*(cos(2*a.*x) + cos(2*b.*y) + cos(2*g.*z));
            
                    name = 'Double SP';
                    
                case 'Schwarz Diamond (SD)'
                   
                    u = (cos(a.*x).* cos(b.*y).* cos(g.*z))- (sin(a).* sin(b.*y).* sin(g.*z));
                   
                    name = 'Schwarz Diamond (SD)';
                    
                case 'Double SD'
                    
                    u = (sin(a.*x).*sin(b.*y) + sin(b.*y).*sin(g.*z) + sin(a.*x).*sin(g.*z) + cos(a.*x).*cos(b.*y).*cos(g.*z));
                    
                    name = 'Double SD';
                    
                case 'IWP'
                  
                    u = (2*(cos(a.*x).*cos(b.*y) + cos(b.*y).*cos(g.*z) + cos(g.*z).*cos(a.*x)) - (cos(2*a.*x)+ cos(2*b.*y) + cos(2*g.*z)));
                    
                    name = 'IWP';
                    
                case 'FKS'
                    
                     u = ((cos(2.*a.*x).*sin(b.*y).*cos(g.*z))+ (cos(a.*x).*cos(2.*b.*y).*cos(g.*z))+ (sin(a.*x).*cos(b.*y).*cos(2.*g.*z)));
               
                     name = 'FKS';
                     
                case 'FRD'
                     
                    u = (4*(cos(a.*x).*cos(b.*y).*cos(g.*z))- ( cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z)+cos(2*g.*z).*cos(2*a.*x)));
                   
                    name = 'FRD';
                    
                case 'Lidinoid'
                    
                      u = 0.5*(sin(2*a.*x).*cos(b.*y).* sin(g.*z) + sin(2*b.*y).*cos(g.*z).*sin(a.*x) + sin(2*g.*z).* cos (a.*x).* sin (b.*y)) ...
        -  0.5*(cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z) + cos(2*g.*z).*cos(2*a.*x)) +0.15;
          
                      name = 'Lidinoid';
                      
                case 'Neovius'
                    
                u =  (4*(cos(a.*x).*cos(b.*y).*cos(g.*z)) + 3*(cos(a.*x) + cos(b.*y) + cos(g.*z))) ; 

                 name = 'Neovius';
                 
                otherwise
                     u = (-0.2 *(cos(2*a.*x).* cos(2*b.*y) + cos(2*a.*x).* cos(2*g.*z) + cos(2*b.*y).* cos(2*g.*z)) ... 
        - 0.4 *(cos(2*a.*x) + cos(2*b.*y) + cos(2*g.*z)) ...
    + 1.1*(sin(a.*x).* sin(2*b.*y).* cos(g.*z) + sin(2*a.*x).* cos(b.*y).* sin(g.*z) + cos(a.*x).* sin(b.*y).* sin(2*g.*z)));
                   
                     name = 'Spli P';
                    
            end        
      
            % Level Set equation 
            c = app.LevelSetKnob.Value;
            
           switch  app.Switch.Value
               case 'Sheet'
                  
                   Te= -(u+c).*(u-c);
                
               otherwise
                   Te= u + c;
               
           end
           
          T = Te;

          close;


           [f,v] = isosurface(T,0);
            [f1,v1] = isocaps(T,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
                
             cla(app.UIAxes);
          
            app.RunningLamp.Color = [0,1,0]; 
            answer = 'Go!';
            app.EditField.Value = answer;

         % title(name);
        
             lm = patch(app.UIAxes,'Faces',f2,'Vertices',v2);
                 
            set(lm,'FaceColor',[0 0.4470 0.7410]);  
            set(lm,'EdgeColor','none');
            app.UIAxes.XTickMode = 'manual';
            app.UIAxes.XTick = ([0  20 40  60 80 100]);
            app.UIAxes.YTickMode = 'manual';
            app.UIAxes.YTick = ([0 20 40 60  80 100]);
            app.UIAxes.ZTickMode = 'manual';
            app.UIAxes.ZTick = ([0  20  40  60 80  100]);
            camlight(app.UIAxes, 'headlight'); 
            light(app.UIAxes,'position',[0 -1 0]);
            colormap(app.UIAxes,'turbo') ;
            lighting(app.UIAxes,'flat') ;
            pbaspect(app.UIAxes,[1,1,1])
            axis(app.UIAxes, 'tight');
            view(app.UIAxes,3);
            title(app.UIAxes,name);
            

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
               global T;

            app.RunningLamp.Color = [1,0,0];          
            drawnow; 
            answer = 'Please wait...';
            app.EditField.Value = answer;
            drawnow; 
           
            [f,v] = isosurface(T,0);
            [f1,v1] = isocaps(T,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
            [F,P] = uiputfile('*.stl');
            stlwrite(app,fullfile(P,F),f2,v2);  


            app.RunningLamp.Color = [0,1,0]; 
            answer = 'Go!';
            app.EditField.Value = answer;

            
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            

          global VD;  % Funtion
             
          app.RunningLamp_2.Color = [1,0,0];          
          drawnow; 
          answer = 'Please wait...';
           app.EditField_2.Value = answer;
           drawnow;

           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cell parameters 
       
            n1 = app.XDirectionEditField_2.Value;
            n2 = app.YDirectionEditField_2.Value;
            n3 = app.ZDirectionEditField_2.Value;
            
            a=2*n1*pi; % x direction parameters
            b=2*n2*pi; % y direction parameters
            g=2*n3*pi; % z direction parameters 
             
            s=0.01;
            
            [x,y,z]=meshgrid(-0.5:s:0.5,-0.5:s:0.5,-0.5:s:0.5);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % min and max of the TPMS 


            switch app.DirectionDropDown.Value
                case 'X'
                    dirmin = min(x,[],'all');
                    dirmax = max(x,[],'all');
                    dir = x;
                    
                case 'Y'
                    dirmin = min(y,[],'all');
                    dirmax = max(y,[],'all');
                    dir = y;
                    
                otherwise
                    dirmin = min(z,[],'all');
                    dirmax = max(z,[],'all');
                    dir = z;
            end            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Desnity change
            
            d1 = app.MaximumSlider.Value;  % minimum density
            
            d2 = app.MinimumSlider.Value;  % Maximum density
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            switch app.FunctionDropDown_2.Value
                case 'Gyroid'
                    
                    u = (sin(a.*x).* cos(b.*y) + sin(b.*y).* cos(g.*z) + sin(g.*z).* cos(a.*x));
                    
                      name = 'Gyroid'; 
                       
                    % VD 
                    
                    sk_c1 = 0.02998*d1 - 1.4999;
                    
                    sk_c2 = 0.02998*d2 - 1.4999;
                    
                    sh_c1 = 1.4999*d1/ 100 ;
                    
                    sh_c2 = 1.4999*d2/ 100 ;
                    
                    
                case 'Double Gyroid'
                    
                     u = 2.75*(sin(2*a.*x).*sin(g.*z).*cos(b.*y) + sin(2*b.*y).*sin(a.*x).*cos(g.*z) + sin(2*g.*z).*sin(b.*y).*cos(a.*x)) ...
      - (cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z) + cos(2*g.*z).*cos(2*a.*x));
                    
                     name = 'Double Gyroid'; 
                    
                    % VD
                    
                    sk_c1 = 0.0695*d1- 3 ;
                     
                    sk_c2 = 0.0695*d2- 3 ;
                    
                    sh_c1 = 3.9546*d1 / 100 ;
                    
                    sh_c2 = 3.9546*d2 / 100 ;


                case 'Schwarz Primitive (SP)'
                    
                    u = cos(a.*x) + cos(b.*y) + cos(g.*z); 
                   
                      name = 'Schwarz Primitive (SP)'; 
                    
                      % VD
                    
                    sk_c1 = 0.06*d1-3 ;
                     
                    sk_c2 = 0.06*d2-3 ;
                    
                    sh_c1 =  0.03*d1;
                   
                    sh_c2 = 0.03*d2;
                      
                      
                case 'Double SP'
                    
                    u = 0.5*(cos(a.*x).*cos(b.*y) + cos(b.*y).*cos(g.*z) + cos(g.*z).*cos(a.*x)) + 0.2*(cos(2*a.*x) + cos(2*b.*y) + cos(2*g.*z));
            
                    name = 'Double SP';
                    
                     % VD
                    
                    sk_c1 = 0.027*d1-0.60 ;
                     
                    sk_c2 = 0.027*d2-0.60 ;
                    
                    sh_c1 =  2.1*d1/100 ;
                   
                    sh_c2 = 2.1*d2/100 ;
                    
                case 'Schwarz Diamond (SD)'
                   
                    u = (cos(a.*x).* cos(b.*y).* cos(g.*z))- (sin(a).* sin(b.*y).* sin(g.*z));
                   
                    name = 'Schwarz Diamond (SD)';
                    
                      % VD
                    
                    sk_c1 = 0.02*d1-1;
                     
                    sk_c2 = 0.02*d2-1;
                    
                    sh_c1 =  0.01*d1;
                   
                    sh_c2 = 0.01*d2;
                    
                case 'Double SD'
                    
                    u = (sin(a.*x).*sin(b.*y) + sin(b.*y).*sin(g.*z) + sin(a.*x).*sin(g.*z) + cos(a.*x).*cos(b.*y).*cos(g.*z));
                    
                    name = 'Double SD';
                    
                    % VD
                    
                    sk_c1 = 0.04*d1-1 ;
                     
                    sk_c2 = 0.04*d2-1 ;
                    
                    sh_c1 =  0.01*d1;
                   
                    sh_c2 = 0.01*d2;
                                      
                    
                case 'IWP'
                  
                    u = (2*(cos(a.*x).*cos(b.*y) + cos(b.*y).*cos(g.*z) + cos(g.*z).*cos(a.*x)) - (cos(2*a.*x)+ cos(2*b.*y) + cos(2*g.*z)));
                    
                    name = 'IWP';
                    
                    %VD 
                    
                    sk_c1 = 0.08*d1-5;
                     
                    sk_c2 = 0.08*d2-5;
                    
                    sh_c1 = 0.05*d1;
                   
                    sh_c2 = 0.05*d2;
                    
                case 'FKS'
                    
                     u = ((cos(2.*a.*x).*sin(b.*y).*cos(g.*z))+ (cos(a.*x).*cos(2.*b.*y).*cos(g.*z))+ (sin(a.*x).*cos(b.*y).*cos(2.*g.*z)));
               
                     name = 'FKS';
                     
                      %VD 
                    
                    sk_c1 = 0.04*d1-2;
                     
                    sk_c2 = 0.04*d2-2;
                    
                    sh_c1 = 0.02*d1;
                                       
                    sh_c2 = 0.02*d2;
                     
                case 'FRD'
                     
                    u = (4*(cos(a.*x).*cos(b.*y).*cos(g.*z))- ( cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z)+cos(2*g.*z).*cos(2*a.*x)));
                   
                    name = 'FRD';
                    
                     %VD 
                    
                    sk_c1 =  0.09*d1-7;
                     
                    sk_c2 = 0.09*d2-7;
                    
                    sh_c1 =  0.07*d1;
                   
                    sh_c2 =  0.07*d2;
                    
                    
                case 'Lidinoid'
                    
                      u = 0.5*(sin(2*a.*x).*cos(b.*y).* sin(g.*z) + sin(2*b.*y).*cos(g.*z).*sin(a.*x) + sin(2*g.*z).* cos (a.*x).* sin (b.*y)) ...
        -  0.5*(cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z) + cos(2*g.*z).*cos(2*a.*x)) +0.15;
          
                      name = 'Lidinoid';
                      
                       %VD 
                    
                    sk_c1 = 0.0248*d1-1.35;
                     
                    sk_c2 = 0.0248*d2-1.35;
                    
                    sh_c1 = 1.35*d1 /100 ;
                   
                    sh_c2 = 1.35*d2 /100 ;
                      
                case 'Neovius'
                    
                u =  (4*(cos(a.*x).*cos(b.*y).*cos(g.*z)) + 3*(cos(a.*x) + cos(b.*y) + cos(g.*z))) ; 

                 name = 'Neovius';
                 
                  %VD 
                    
                    sk_c1 = 0.26*d1 - 13;
                     
                    sk_c2 =  0.26*d2 - 13;
                    
                    sh_c1 = 0.13*d1;
                   
                    sh_c2 = 0.13*d2;
                 
                otherwise
                     u = (-0.2 *(cos(2*a.*x).* cos(2*b.*y) + cos(2*a.*x).* cos(2*g.*z) + cos(2*b.*y).* cos(2*g.*z)) ... 
        - 0.4 *(cos(2*a.*x) + cos(2*b.*y) + cos(2*g.*z)) ...
    + 1.1*(sin(a.*x).* sin(2*b.*y).* cos(g.*z) + sin(2*a.*x).* cos(b.*y).* sin(g.*z) + cos(a.*x).* sin(b.*y).* sin(2*g.*z)));
                   
                    name = 'Spli P';
                    
                     %VD 
                    
                    sk_c1 = 0.0361*d1 - 1.8;
                     
                    sk_c2 = 0.0361*d2 - 1.8;
                    
                    sh_c1 = 1.8*d1 /100 ;
                   
                    sh_c2 = 1.8*d2 /100 ;
                    
            end        
      
                     
           switch  app.Switch_2.Value
               case 'Sheet'


                   m = (sh_c2 - sh_c1)/ (dirmax - dirmin);
                   
                   c1= (m*dir) - (m*dirmin) + sh_c1;
                   
                    T = -(u+c1).*(u-c1);

                   VDT = T ;
                
               otherwise
                   
                   m = (sk_c2 - sk_c1)/ (dirmax - dirmin);
                   
                   c1= (m*dir) - (m*dirmin) + sk_c1;
               
                   VDT = u + c1;
           end
           
         
          VD = VDT;

          
           [f,v] = isosurface(VD,0);
            [f1,v1] = isocaps(VD,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
                
            cla(app.UIAxes_2);
          
            app.RunningLamp_2.Color = [0,1,0]; 
            answer = 'Go!';
            app.EditField_2.Value = answer;

         % title(name);
        
             lm = patch(app.UIAxes_2,'Faces',f2,'Vertices',v2);
                 
            set(lm,'FaceColor',[0 0.4470 0.7410]);  
            set(lm,'EdgeColor','none');
            app.UIAxes_2.XTickMode = 'manual';
            app.UIAxes_2.XTick = ([0  20 40  60 80 100]);
            app.UIAxes_2.YTickMode = 'manual';
            app.UIAxes_2.YTick = ([0 20 40 60  80 100]);
            app.UIAxes_2.ZTickMode = 'manual';
            app.UIAxes_2.ZTick = ([0  20  40  60 80  100]);
            camlight(app.UIAxes_2, 'headlight'); 
            light(app.UIAxes_2,'position',[0 -1 0]);
            colormap(app.UIAxes_2,'turbo') ;
            lighting(app.UIAxes_2,'flat') ;
            pbaspect(app.UIAxes_2,[1,1,1])
            axis(app.UIAxes_2, 'tight');
            view(app.UIAxes_2,3);
            title(app.UIAxes_2,name);


        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            
            global VD;

           app.RunningLamp_2.Color = [1,0,0];          
           drawnow; 
           answer = 'Please wait...';
           app.EditField_2.Value = answer;
           drawnow;

           
            [f,v] = isosurface(VD,0);
            [f1,v1] = isocaps(VD,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
            [F,P] = uiputfile('*.stl');
            stlwrite(app,fullfile(P,F),f2,v2);  

              
            app.RunningLamp_2.Color = [0,1,0]; 
            answer = 'Go!';
            app.EditField_2.Value = answer;


        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            
        global CG;  % Funtion
            
           app.RunningLamp_3.Color = [1,0,0];          
           drawnow; 
           answer = 'Please wait...';
           app.EditField_3.Value = answer;
           drawnow;
            
         
           d=(1.35*pi);   
           d2=(2.65*pi); 
           s=pi/30; 

           [x,y,z]=meshgrid(-d:s:d2,-d:s:d2,-d:s:d2);
            
           Zmax = max(z(:));
           Zmin = min(z(:));
           
           m1 = app.EndingcellsEditField.Value;
           n1 = app.StartingcellsEditField.Value;
           m = m1/2;
           n = n1/2;
           
           k1 = (m-n)/(Zmax - Zmin) ; 
           c0 = (k1*Zmin*Zmin)/2;
           c1 = -(Zmin*k1) + n;

  
           a= (k1*z + c1);
           b= (k1*z + c1);
           g= (((k1*z)/ 2) + c1 + (c0/z));
            
            
            switch app.FunctionDropDown_3.Value
                case 'Gyroid'
                    
                    u = (sin(a.*x).* cos(b.*y) + sin(b.*y).* cos(g.*z) + sin(g.*z).* cos(a.*x));
                    
                       name = 'Gyroid'; 
                    
                case 'Double Gyroid'
                    
                     u = 2.75*(sin(2*a.*x).*sin(g.*z).*cos(b.*y) + sin(2*b.*y).*sin(a.*x).*cos(g.*z) + sin(2*g.*z).*sin(b.*y).*cos(a.*x)) ...
      - (cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z) + cos(2*g.*z).*cos(2*a.*x));
                    
                     name = 'Double Gyroid'; 
                    
                case 'Schwarz Primitive (SP)'
                    
                    u = cos(a.*x) + cos(b.*y) + cos(g.*z); 
                   
                      name = 'Schwarz Primitive (SP)'; 
                    
                case 'Double SP'
                    
                    u = 0.5*(cos(a.*x).*cos(b.*y) + cos(b.*y).*cos(g.*z) + cos(g.*z).*cos(a.*x)) + 0.2*(cos(2*a.*x) + cos(2*b.*y) + cos(2*g.*z));
            
                    name = 'Double SP';
                    
                case 'Schwarz Diamond (SD)'
                   
                    u = (cos(a.*x).* cos(b.*y).* cos(g.*z))- (sin(a).* sin(b.*y).* sin(g.*z));
                   
                    name = 'Schwarz Diamond (SD)';
                    
                case 'Double SD'
                    
                    u = (sin(a.*x).*sin(b.*y) + sin(b.*y).*sin(g.*z) + sin(a.*x).*sin(g.*z) + cos(a.*x).*cos(b.*y).*cos(g.*z));
                    
                    name = 'Double SD';
                    
                case 'IWP'
                  
                    u = (2*(cos(a.*x).*cos(b.*y) + cos(b.*y).*cos(g.*z) + cos(g.*z).*cos(a.*x)) - (cos(2*a.*x)+ cos(2*b.*y) + cos(2*g.*z)));
                    
                    name = 'IWP';
                    
                case 'FKS'
                    
                     u = ((cos(2.*a.*x).*sin(b.*y).*cos(g.*z))+ (cos(a.*x).*cos(2.*b.*y).*cos(g.*z))+ (sin(a.*x).*cos(b.*y).*cos(2.*g.*z)));
               
                     name = 'FKS';
                     
                case 'FRD'
                     
                    u = (4*(cos(a.*x).*cos(b.*y).*cos(g.*z))- ( cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z)+cos(2*g.*z).*cos(2*a.*x)));
                   
                    name = 'FRD';
                    
                case 'Lidinoid'
                    
                      u = 0.5*(sin(2*a.*x).*cos(b.*y).* sin(g.*z) + sin(2*b.*y).*cos(g.*z).*sin(a.*x) + sin(2*g.*z).* cos (a.*x).* sin (b.*y)) ...
        -  0.5*(cos(2*a.*x).*cos(2*b.*y) + cos(2*b.*y).*cos(2*g.*z) + cos(2*g.*z).*cos(2*a.*x)) +0.15;
          
                      name = 'Lidinoid';
                      
                case 'Neovius'
                    
                u =  (4*(cos(a.*x).*cos(b.*y).*cos(g.*z)) + 3*(cos(a.*x) + cos(b.*y) + cos(g.*z))) ; 

                 name = 'Neovius';
                 
                otherwise
                     u = (-0.2 *(cos(2*a.*x).* cos(2*b.*y) + cos(2*a.*x).* cos(2*g.*z) + cos(2*b.*y).* cos(2*g.*z)) ... 
        - 0.4 *(cos(2*a.*x) + cos(2*b.*y) + cos(2*g.*z)) ...
    + 1.1*(sin(a.*x).* sin(2*b.*y).* cos(g.*z) + sin(2*a.*x).* cos(b.*y).* sin(g.*z) + cos(a.*x).* sin(b.*y).* sin(2*g.*z)));
                   
                     name = 'Spli P';
                    
            end        
      
            % Level Set equation 
            c = app.LevelSetKnob_2.Value;
            
           switch  app.Switch_3.Value
               case 'Sheet'
                  
                   CGtemp = -(u+c).*(u-c);
                
               otherwise
                   CGtemp = u + c;
               
           end
           
           %%%%%%%%%% 

           CG = CGtemp;

            [f,v] = isosurface(CG,0);
            [f1,v1] = isocaps(CG,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
          
            cla(app.UIAxes_3);
          
            app.RunningLamp_3.Color = [0,1,0]; 
           
            answer = 'Go!';
            app.EditField_3.Value = answer;
            

           % title(name);
        
             lm = patch(app.UIAxes_3,'Faces',f2,'Vertices',v2);
                 
            set(lm,'FaceColor',[0 0.4470 0.7410]);  
            set(lm,'EdgeColor','none');
            app.UIAxes_3.XTickMode = 'manual';
            app.UIAxes_3.XTick = ([0  20 40  60 80 100 120 140]);
            app.UIAxes_3.YTickMode = 'manual';
            app.UIAxes_3.YTick = ([0 20 40 60  80 100 120 140]);
            app.UIAxes_3.ZTickMode = 'manual';
            app.UIAxes_3.ZTick = ([0  20  40  60 80  100 120 140]);
            camlight(app.UIAxes_3, 'headlight'); 
            light(app.UIAxes_3,'position',[0 -1 0]);
            colormap(app.UIAxes_3,'turbo') ;
            lighting(app.UIAxes_3,'flat') ;
            pbaspect(app.UIAxes_3,[1,1,1])
            axis(app.UIAxes_3, 'tight');
            view(app.UIAxes_3,3);
            title(app.UIAxes_3,name);


        end

        % Button pushed function: ExportButton_3
        function ExportButton_3Pushed(app, event)
            
          global CG;


        app.RunningLamp_3.Color = [1,0,0];          
           drawnow; 
           answer = 'Please wait...';
           app.EditField_3.Value = answer;
           drawnow;

             [f,v] = isosurface(CG,0);
            [f1,v1] = isocaps(CG,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
            [F,P] = uiputfile('*.stl');
            stlwrite(app,fullfile(P,F),f2,v2);  

            app.RunningLamp_3.Color = [0,1,0]; 
            answer = 'Go!';
            app.EditField_3.Value = answer;


        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
             global h;
        
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Defining the parameters for function 1
            
            app.RunningLamp_4.Color = [1,0,0]; 
            drawnow;
            answer = 'Please wait...';
            app.EditField_4.Value = answer;
            drawnow;

            n1 = app.XEditField.Value;
            n2 = app.YEditField.Value;
            n3 = app.ZEditField.Value;
            
            a1=2*n1*pi; % x direction parameters
            b1=2*n2*pi; % y direction parameters
            g1=2*n3*pi;  % z direction parametes 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Defining the parameters for function 2
            
            n4 = app.XEditField_2.Value;
            n5 = app.YEditField_2.Value;
            n6 = app.ZEditField_2.Value;
            
            a2=2*n4*pi; % x direction parameters
            b2=2*n5*pi; % y direction parameters
            g2=2*n6*pi;  % z direction parametes 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            s=0.01;
            [x,y,z]=meshgrid(-0.5:s:0.5,-0.5:s:0.5,-0.5:s:0.5);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Function 1
          
             switch app.Function1DropDown.Value
     
                case 'Gyroid'
                    
                    p1 = (sin(a1.*x).* cos(b1.*y) + sin(b1.*y).* cos(g1.*z) + sin(g1.*z).* cos(a1.*x));


                case 'Double Gyroid'
                    
                     p1 = 2.75*(sin(2*a1.*x).*sin(g1.*z).*cos(b1.*y) + sin(2*b1.*y).*sin(a1.*x).*cos(g1.*z) + sin(2*g1.*z).*sin(b1.*y).*cos(a1.*x)) ...
      - (cos(2*a1.*x).*cos(2*b1.*y) + cos(2*b1.*y).*cos(2*g1.*z) + cos(2*g1.*z).*cos(2*a1.*x));


                case 'Schwarz Primitive (SP)'
                    
                    p1 = cos(a1.*x) + cos(b1.*y) + cos(g1.*z); 


                case 'Double SP'
                    
                    p1 = 0.5*(cos(a1.*x).*cos(b1.*y) + cos(b1.*y).*cos(g1.*z) + cos(g1.*z).*cos(a1.*x)) + 0.2*(cos(2*a1.*x) + cos(2*b1.*y) + cos(2*g1.*z));
            
                   
                case 'Schwarz Diamond (SD)'
                   
                    p1 = (cos(a1.*x).* cos(b1.*y).* cos(g1.*z))- (sin(a1).* sin(b1.*y).* sin(g1.*z));


                case 'Double SD'
                    
                    p1 = (sin(a1.*x).*sin(b1.*y) + sin(b1.*y).*sin(g1.*z) + sin(a1.*x).*sin(g1.*z) + cos(a1.*x).*cos(b1.*y).*cos(g1.*z));


                case 'IWP'
                  
                    p1 = (2*(cos(a1.*x).*cos(b1.*y) + cos(b1.*y).*cos(g1.*z) + cos(g1.*z).*cos(a1.*x)) - (cos(2*a1.*x)+ cos(2*b1.*y) + cos(2*g1.*z)));


                case 'FKS'
                    
                     p1 = ((cos(2.*a1.*x).*sin(b1.*y).*cos(g1.*z))+ (cos(a1.*x).*cos(2.*b1.*y).*cos(g1.*z))+ (sin(a1.*x).*cos(b1.*y).*cos(2.*g1.*z)));


                case 'FRD'
                     
                    p1 = (4*(cos(a1.*x).*cos(b1.*y).*cos(g1.*z))- ( cos(2*a1.*x).*cos(2*b1.*y) + cos(2*b1.*y).*cos(2*g1.*z)+cos(2*g1.*z).*cos(2*a1.*x)));
                   
                   
                case 'Lidinoid'
                    
                      p1 = 0.5*(sin(2*a1.*x).*cos(b1.*y).* sin(g1.*z) + sin(2*b1.*y).*cos(g1.*z).*sin(a1.*x) + sin(2*g1.*z).* cos (a1.*x).* sin (b1.*y)) ...
        -  0.5*(cos(2*a1.*x).*cos(2*b1.*y) + cos(2*b1.*y).*cos(2*g1.*z) + cos(2*g1.*z).*cos(2*a1.*x)) +0.15;


                case 'Neovius'
                    
                p1 =  (4*(cos(a1.*x).*cos(b1.*y).*cos(g1.*z)) + 3*(cos(a1.*x) + cos(b1.*y) + cos(g1.*z))) ; 

               
                otherwise
                     p1 = (-0.2 *(cos(2*a1.*x).* cos(2*b1.*y) + cos(2*a1.*x).* cos(2*g1.*z) + cos(2*b1.*y).* cos(2*g1.*z)) ... 
        - 0.4 *(cos(2*a1.*x) + cos(2*b1.*y) + cos(2*g1.*z)) ...
    + 1.1*(sin(a1.*x).* sin(2*b1.*y).* cos(g1.*z) + sin(2*a1.*x).* cos(b1.*y).* sin(g1.*z) + cos(a1.*x).* sin(b1.*y).* sin(2*g1.*z)));


            end  
       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
        % Function 2
        
         switch app.Function2DropDown.Value
             
             case 'Gyroid'
                    
                    p2 = (sin(a2.*x).* cos(b2.*y) + sin(b2.*y).* cos(g2.*z) + sin(g2.*z).* cos(a2.*x));
                    
                    
                case 'Double Gyroid'
                    
                     p2 = 2.75*(sin(2*a2.*x).*sin(g2.*z).*cos(b2.*y) + sin(2*b2.*y).*sin(a2.*x).*cos(g2.*z) + sin(2*g2.*z).*sin(b2.*y).*cos(a2.*x)) ...
      - (cos(2*a2.*x).*cos(2*b2.*y) + cos(2*b2.*y).*cos(2*g2.*z) + cos(2*g2.*z).*cos(2*a2.*x));


                case 'Schwarz Primitive (SP)'
                    
                    p2 = cos(a2.*x) + cos(b2.*y) + cos(g2.*z); 


                case 'Double SP'
                    
                    p2 = 0.5*(cos(a2.*x).*cos(b2.*y) + cos(b2.*y).*cos(g2.*z) + cos(g2.*z).*cos(a2.*x)) + 0.2*(cos(2*a2.*x) + cos(2*b2.*y) + cos(2*g2.*z));


                case 'Schwarz Diamond (SD)'
                   
                    p2 = (cos(a2.*x).* cos(b2.*y).* cos(g2.*z))- (sin(a2).* sin(b2.*y).* sin(g2.*z));
                   
                    
                case 'Double SD'
                    
                    p2 = (sin(a2.*x).*sin(b2.*y) + sin(b2.*y).*sin(g2.*z) + sin(a2.*x).*sin(g2.*z) + cos(a2.*x).*cos(b2.*y).*cos(g2.*z));


                case 'IWP'
                  
                    p2 = (2*(cos(a2.*x).*cos(b2.*y) + cos(b2.*y).*cos(g2.*z) + cos(g2.*z).*cos(a2.*x)) - (cos(2*a2.*x)+ cos(2*b2.*y) + cos(2*g2.*z)));


                case 'FKS'
                    
                     p2 = ((cos(2.*a2.*x).*sin(b2.*y).*cos(g2.*z))+ (cos(a2.*x).*cos(2.*b2.*y).*cos(g2.*z))+ (sin(a2.*x).*cos(b2.*y).*cos(2.*g2.*z)));


                case 'FRD'
                     
                    p2 = (4*(cos(a2.*x).*cos(b2.*y).*cos(g2.*z))- ( cos(2*a2.*x).*cos(2*b2.*y) + cos(2*b2.*y).*cos(2*g2.*z)+cos(2*g2.*z).*cos(2*a2.*x)));


                case 'Lidinoid'
                    
                      p2 = 0.5*(sin(2*a2.*x).*cos(b2.*y).* sin(g2.*z) + sin(2*b2.*y).*cos(g2.*z).*sin(a2.*x) + sin(2*g2.*z).* cos (a2.*x).* sin (b2.*y)) ...
        -  0.5*(cos(2*a2.*x).*cos(2*b2.*y) + cos(2*b2.*y).*cos(2*g2.*z) + cos(2*g2.*z).*cos(2*a2.*x)) +0.15;


                case 'Neovius'
                    
                p2 =  (4*(cos(a2.*x).*cos(b2.*y).*cos(g2.*z)) + 3*(cos(a2.*x) + cos(b2.*y) + cos(g2.*z))) ; 


                otherwise
                     p2 = (-0.2 *(cos(2*a2.*x).* cos(2*b2.*y) + cos(2*a2.*x).* cos(2*g2.*z) + cos(2*b2.*y).* cos(2*g2.*z)) ... 
        - 0.4 *(cos(2*a2.*x) + cos(2*b2.*y) + cos(2*g2.*z)) ...
    + 1.1*(sin(a2.*x).* sin(2*b2.*y).* cos(g2.*z) + sin(2*a2.*x).* cos(b2.*y).* sin(g2.*z) + cos(a2.*x).* sin(b2.*y).* sin(2*g2.*z)));


         end
       
     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     
       % direction
       
       
            switch app.DirectionDropDown_2.Value
                case 'X'
                    
                    dir = x;
                    
                case 'Y'
                   
                    dir = y;
                    
                otherwise
                   
                    dir = z;
            end        
         
     
     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     
       % for hybridization
       
       k = app.SigmoidFunctionkEditField.Value;
       

       hy= 1/ (1+ exp((k.*dir)));
       
        u = hy.*p1 + (1-hy).*p2 ;
       
     %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
     
     % ploting
     
      % Level Set equation 
            c = app.LevelSetKnob_3.Value;
            
           switch  app.Switch_4.Value
               case 'Sheet'
                  
                   htemp= -(u+c).*(u-c);
                   
               otherwise
                   
                     htemp= u + c;
                  
               
           end
           
           h = htemp;

            close;
           

            [f,v] = isosurface(h,0);
            [f1,v1] = isocaps(h,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
          
            cla(app.UIAxes_4);
          
            app.RunningLamp_4.Color = [0,1,0]; 
           
            answer = 'Go!';
            app.EditField_4.Value = answer;
            

           name = 'Hybridization';
        
             lm = patch(app.UIAxes_4,'Faces',f2,'Vertices',v2);
                 
            set(lm,'FaceColor',[0 0.4470 0.7410]);  
            set(lm,'EdgeColor','none');
            app.UIAxes_4.XTickMode = 'manual';
            app.UIAxes_4.XTick = ([0  20 40  60 80 100 120 140]);
            app.UIAxes_4.YTickMode = 'manual';
            app.UIAxes_4.YTick = ([0 20 40 60  80 100 120 140]);
            app.UIAxes_4.ZTickMode = 'manual';
            app.UIAxes_4.ZTick = ([0  20  40  60 80  100 120 140]);
            camlight(app.UIAxes_4, 'headlight'); 
            light(app.UIAxes_4,'position',[0 -1 0]);
            colormap(app.UIAxes_4,'turbo') ;
            lighting(app.UIAxes_4,'flat') ;
            pbaspect(app.UIAxes_4,[1,1,1])
            axis(app.UIAxes_4, 'tight');
            view(app.UIAxes_4,3);
            title(app.UIAxes_4,name);


        end

        % Button pushed function: ExportButton_4
        function ExportButton_4Pushed(app, event)
           global h;
      
            app.RunningLamp_4.Color = [1,0,0]; 
            drawnow;
            answer = 'Please wait...';
            app.EditField_4.Value = answer;
            drawnow;


            [f,v] = isosurface(h,0);
            [f1,v1] = isocaps(h,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
            [F,P] = uiputfile('*.stl');
            stlwrite(app,fullfile(P,F),f2,v2); 

         
            app.RunningLamp_4.Color = [0,1,0]; 
           
            answer = 'Go!';
            app.EditField_4.Value = answer;


        end

        % Button pushed function: PlotButton_5
        function PlotButton_5Pushed(app, event)
            global hh;
        
            app.RunningLamp_5.Color = [1,0,0]; 
            drawnow;
            answer = 'Please wait...';
            app.EditField_5.Value = answer;
            drawnow;
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Defining the parameters for function 1

                       
            n1 = app.XEditField_3.Value;
            n2 = app.YEditField_3.Value;
            n3 = app.ZEditField_3.Value;
            
            a1=2*n1*pi; % x direction parameters
            b1=2*n2*pi; % y direction parameters
            g1=2*n3*pi;  % z direction parametes 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Defining the parameters for function 2
            
            n4 = app.XEditField_4.Value;
            n5 = app.YEditField_4.Value;
            n6 = app.ZEditField_4.Value;
            
            a2=2*n4*pi; % x direction parameters
            b2=2*n5*pi; % y direction parameters
            g2=2*n6*pi;  % z direction parametes 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            s=0.01;
            [x,y,z]=meshgrid(-0.5:s:0.5,-0.5:s:0.5,-0.5:s:0.5);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Function 1
          
             switch app.Function1DropDown_2.Value
     
                case 'Gyroid'
                    
                    p1 = (sin(a1.*x).* cos(b1.*y) + sin(b1.*y).* cos(g1.*z) + sin(g1.*z).* cos(a1.*x));


                case 'Double Gyroid'
                    
                     p1 = 2.75*(sin(2*a1.*x).*sin(g1.*z).*cos(b1.*y) + sin(2*b1.*y).*sin(a1.*x).*cos(g1.*z) + sin(2*g1.*z).*sin(b1.*y).*cos(a1.*x)) ...
      - (cos(2*a1.*x).*cos(2*b1.*y) + cos(2*b1.*y).*cos(2*g1.*z) + cos(2*g1.*z).*cos(2*a1.*x));


                case 'Schwarz Primitive (SP)'
                    
                    p1 = cos(a1.*x) + cos(b1.*y) + cos(g1.*z); 


                case 'Double SP'
                    
                    p1 = 0.5*(cos(a1.*x).*cos(b1.*y) + cos(b1.*y).*cos(g1.*z) + cos(g1.*z).*cos(a1.*x)) + 0.2*(cos(2*a1.*x) + cos(2*b1.*y) + cos(2*g1.*z));
            
                   
                case 'Schwarz Diamond (SD)'
                   
                    p1 = (cos(a1.*x).* cos(b1.*y).* cos(g1.*z))- (sin(a1).* sin(b1.*y).* sin(g1.*z));


                case 'Double SD'
                    
                    p1 = (sin(a1.*x).*sin(b1.*y) + sin(b1.*y).*sin(g1.*z) + sin(a1.*x).*sin(g1.*z) + cos(a1.*x).*cos(b1.*y).*cos(g1.*z));


                case 'IWP'
                  
                    p1 = (2*(cos(a1.*x).*cos(b1.*y) + cos(b1.*y).*cos(g1.*z) + cos(g1.*z).*cos(a1.*x)) - (cos(2*a1.*x)+ cos(2*b1.*y) + cos(2*g1.*z)));


                case 'FKS'
                    
                     p1 = ((cos(2.*a1.*x).*sin(b1.*y).*cos(g1.*z))+ (cos(a1.*x).*cos(2.*b1.*y).*cos(g1.*z))+ (sin(a1.*x).*cos(b1.*y).*cos(2.*g1.*z)));


                case 'FRD'
                     
                    p1 = (4*(cos(a1.*x).*cos(b1.*y).*cos(g1.*z))- ( cos(2*a1.*x).*cos(2*b1.*y) + cos(2*b1.*y).*cos(2*g1.*z)+cos(2*g1.*z).*cos(2*a1.*x)));
                   
                   
                case 'Lidinoid'
                    
                      p1 = 0.5*(sin(2*a1.*x).*cos(b1.*y).* sin(g1.*z) + sin(2*b1.*y).*cos(g1.*z).*sin(a1.*x) + sin(2*g1.*z).* cos (a1.*x).* sin (b1.*y)) ...
        -  0.5*(cos(2*a1.*x).*cos(2*b1.*y) + cos(2*b1.*y).*cos(2*g1.*z) + cos(2*g1.*z).*cos(2*a1.*x)) +0.15;


                case 'Neovius'
                    
                p1 =  (4*(cos(a1.*x).*cos(b1.*y).*cos(g1.*z)) + 3*(cos(a1.*x) + cos(b1.*y) + cos(g1.*z))) ; 

               
                otherwise
                     p1 = (-0.2 *(cos(2*a1.*x).* cos(2*b1.*y) + cos(2*a1.*x).* cos(2*g1.*z) + cos(2*b1.*y).* cos(2*g1.*z)) ... 
        - 0.4 *(cos(2*a1.*x) + cos(2*b1.*y) + cos(2*g1.*z)) ...
    + 1.1*(sin(a1.*x).* sin(2*b1.*y).* cos(g1.*z) + sin(2*a1.*x).* cos(b1.*y).* sin(g1.*z) + cos(a1.*x).* sin(b1.*y).* sin(2*g1.*z)));


            end  
       %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             
        % Function 2
        
         switch app.Function2DropDown_2.Value
             
             case 'Gyroid'
                    
                    p2 = (sin(a2.*x).* cos(b2.*y) + sin(b2.*y).* cos(g2.*z) + sin(g2.*z).* cos(a2.*x));
                    
                    
                case 'Double Gyroid'
                    
                     p2 = 2.75*(sin(2*a2.*x).*sin(g2.*z).*cos(b2.*y) + sin(2*b2.*y).*sin(a2.*x).*cos(g2.*z) + sin(2*g2.*z).*sin(b2.*y).*cos(a2.*x)) ...
      - (cos(2*a2.*x).*cos(2*b2.*y) + cos(2*b2.*y).*cos(2*g2.*z) + cos(2*g2.*z).*cos(2*a2.*x));


                case 'Schwarz Primitive (SP)'
                    
                    p2 = cos(a2.*x) + cos(b2.*y) + cos(g2.*z); 


                case 'Double SP'
                    
                    p2 = 0.5*(cos(a2.*x).*cos(b2.*y) + cos(b2.*y).*cos(g2.*z) + cos(g2.*z).*cos(a2.*x)) + 0.2*(cos(2*a2.*x) + cos(2*b2.*y) + cos(2*g2.*z));


                case 'Schwarz Diamond (SD)'
                   
                    p2 = (cos(a2.*x).* cos(b2.*y).* cos(g2.*z))- (sin(a2).* sin(b2.*y).* sin(g2.*z));
                   
                    
                case 'Double SD'
                    
                    p2 = (sin(a2.*x).*sin(b2.*y) + sin(b2.*y).*sin(g2.*z) + sin(a2.*x).*sin(g2.*z) + cos(a2.*x).*cos(b2.*y).*cos(g2.*z));


                case 'IWP'
                  
                    p2 = (2*(cos(a2.*x).*cos(b2.*y) + cos(b2.*y).*cos(g2.*z) + cos(g2.*z).*cos(a2.*x)) - (cos(2*a2.*x)+ cos(2*b2.*y) + cos(2*g2.*z)));


                case 'FKS'
                    
                     p2 = ((cos(2.*a2.*x).*sin(b2.*y).*cos(g2.*z))+ (cos(a2.*x).*cos(2.*b2.*y).*cos(g2.*z))+ (sin(a2.*x).*cos(b2.*y).*cos(2.*g2.*z)));


                case 'FRD'
                     
                    p2 = (4*(cos(a2.*x).*cos(b2.*y).*cos(g2.*z))- ( cos(2*a2.*x).*cos(2*b2.*y) + cos(2*b2.*y).*cos(2*g2.*z)+cos(2*g2.*z).*cos(2*a2.*x)));


                case 'Lidinoid'
                    
                      p2 = 0.5*(sin(2*a2.*x).*cos(b2.*y).* sin(g2.*z) + sin(2*b2.*y).*cos(g2.*z).*sin(a2.*x) + sin(2*g2.*z).* cos (a2.*x).* sin (b2.*y)) ...
        -  0.5*(cos(2*a2.*x).*cos(2*b2.*y) + cos(2*b2.*y).*cos(2*g2.*z) + cos(2*g2.*z).*cos(2*a2.*x)) +0.15;


                case 'Neovius'
                    
                p2 =  (4*(cos(a2.*x).*cos(b2.*y).*cos(g2.*z)) + 3*(cos(a2.*x) + cos(b2.*y) + cos(g2.*z))) ; 


                otherwise
                     p2 = (-0.2 *(cos(2*a2.*x).* cos(2*b2.*y) + cos(2*a2.*x).* cos(2*g2.*z) + cos(2*b2.*y).* cos(2*g2.*z)) ... 
        - 0.4 *(cos(2*a2.*x) + cos(2*b2.*y) + cos(2*g2.*z)) ...
    + 1.1*(sin(a2.*x).* sin(2*b2.*y).* cos(g2.*z) + sin(2*a2.*x).* cos(b2.*y).* sin(g2.*z) + cos(a2.*x).* sin(b2.*y).* sin(2*g2.*z)));


         end
    
     
     % ploting
     
      % Level Set equation 
            c1 = app.LevelSetFunction1Knob.Value;
            c2 = app.LevelSetFunction2Knob.Value;
            
           switch  app.Switch_5.Value
               case 'Sheet'
                  
                   u1 = -(p1+c1).*(p1-c1);
                   u2 = -(p2+c2).*(p2-c2);
                   
               otherwise
                   
                     u1 = p1 + c1;
                     u2 = p2 + c2;
                  
               
           end
           
           hh = min(u1,u2);
 

           [f,v] = isosurface(hh,0);
            [f1,v1] = isocaps(hh,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
          
            cla(app.UIAxes_5);
          
            app.RunningLamp_5.Color = [0,1,0]; 
           
            answer = 'Go!';
            app.EditField_5.Value = answer;
            

            name = 'Hierarchical';
        
             lm = patch(app.UIAxes_5,'Faces',f2,'Vertices',v2);
                 
            set(lm,'FaceColor',[0 0.4470 0.7410]);  
            set(lm,'EdgeColor','none');
            app.UIAxes_5.XTickMode = 'manual';
            app.UIAxes_5.XTick = ([0  20 40  60 80 100 120 140]);
            app.UIAxes_5.YTickMode = 'manual';
            app.UIAxes_5.YTick = ([0 20 40 60  80 100 120 140]);
            app.UIAxes_5.ZTickMode = 'manual';
            app.UIAxes_5.ZTick = ([0  20  40  60 80  100 120 140]);
            camlight(app.UIAxes_5, 'headlight'); 
            light(app.UIAxes_5,'position',[0 -1 0]);
            colormap(app.UIAxes_5,'turbo') ;
            lighting(app.UIAxes_5,'flat') ;
            pbaspect(app.UIAxes_5,[1,1,1])
            axis(app.UIAxes_5, 'tight');
            view(app.UIAxes_5,3);
            title(app.UIAxes_5,name);


        end

        % Button pushed function: ExportButton_5
        function ExportButton_5Pushed(app, event)
          global hh;

           app.RunningLamp_5.Color = [1,0,0]; 
            drawnow;
            answer = 'Please wait...';
            app.EditField_5.Value = answer;
            drawnow;

           
            [f,v] = isosurface(hh,0);
            [f1,v1] = isocaps(hh,0);
            f2 = [f ; f1+length(v(:,1))];
            v2 = [v ; v1];
            [F,P] = uiputfile('*.stl');
            stlwrite(app,fullfile(P,F),f2,v2); 

            app.RunningLamp_5.Color = [0,1,0]; 
           
            answer = 'Go!';
            app.EditField_5.Value = answer;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimpleTab = uitab(app.TabGroup);
            app.SimpleTab.Title = 'Simple';
            app.Panel = uipanel(app.SimpleTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Figure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CellParametersLabel = uilabel(app.SimpleTab);
            app.CellParametersLabel.Text = 'Cell Parameters';
            app.XDirectionEditFieldLabel = uilabel(app.SimpleTab);
            app.XDirectionEditFieldLabel.Text = 'X Direction';
            app.XDirectionEditField = uieditfield(app.SimpleTab, 'numeric');
            app.XDirectionEditField.Limits = [1 100];
            app.XDirectionEditField.RoundFractionalValues = 'on';
            app.XDirectionEditField.Value = 1;
            app.YDirectionEditFieldLabel = uilabel(app.SimpleTab);
            app.YDirectionEditFieldLabel.Text = 'Y Direction';
            app.YDirectionEditField = uieditfield(app.SimpleTab, 'numeric');
            app.YDirectionEditField.Limits = [1 100];
            app.YDirectionEditField.RoundFractionalValues = 'on';
            app.YDirectionEditField.Value = 1;
            app.ZDirectionEditFieldLabel = uilabel(app.SimpleTab);
            app.ZDirectionEditFieldLabel.Text = 'Z Direction';
            app.ZDirectionEditField = uieditfield(app.SimpleTab, 'numeric');
            app.ZDirectionEditField.Limits = [1 100];
            app.ZDirectionEditField.RoundFractionalValues = 'on';
            app.ZDirectionEditField.Value = 1;
            app.LevelSetKnob = uiknob(app.SimpleTab, 'continuous');
            app.LevelSetKnob.Limits = [-5 5];
            app.LevelSetKnob.Value = 0.3;
            app.LevelSetKnobLabel = uilabel(app.SimpleTab);
            app.LevelSetKnobLabel.Text = 'Level Set ';
            app.FunctionDropDown = uidropdown(app.SimpleTab);
            app.FunctionDropDown.Items = {'Gyroid', 'Double Gyroid', 'Schwarz Primitive (SP)', 'Double SP', 'Schwarz Diamond (SD)', 'Double SD', 'IWP', 'FKS', 'FRD', 'Lidinoid', 'Neovius', 'Split P'};
            app.FunctionDropDown.Value = 'Gyroid';
            app.FunctionDropDownLabel = uilabel(app.SimpleTab);
            app.FunctionDropDownLabel.Text = 'Function';
            app.Switch = uiswitch(app.SimpleTab, 'slider');
            app.Switch.Items = {'Sheet', 'Skeletal'};
            app.Switch.Value = 'Sheet';
            app.EditField = uieditfield(app.SimpleTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 'Go!';
            app.RunningLamp = uilamp(app.SimpleTab);
            app.PlotButton = uibutton(app.SimpleTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExportButton = uibutton(app.SimpleTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.VariableDensityTab = uitab(app.TabGroup);
            app.VariableDensityTab.Title = 'Variable Density';
            app.Panel_2 = uipanel(app.VariableDensityTab);
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, 'Figure')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.CellParametersLabel_2 = uilabel(app.VariableDensityTab);
            app.CellParametersLabel_2.Text = 'Cell Parameters';
            app.XDirectionEditField_2Label = uilabel(app.VariableDensityTab);
            app.XDirectionEditField_2Label.Text = 'X Direction';
            app.XDirectionEditField_2 = uieditfield(app.VariableDensityTab, 'numeric');
            app.XDirectionEditField_2.Limits = [1 100];
            app.XDirectionEditField_2.RoundFractionalValues = 'on';
            app.XDirectionEditField_2.Value = 1;
            app.YDirectionEditField_2Label = uilabel(app.VariableDensityTab);
            app.YDirectionEditField_2Label.Text = 'Y Direction';
            app.YDirectionEditField_2 = uieditfield(app.VariableDensityTab, 'numeric');
            app.YDirectionEditField_2.Limits = [1 100];
            app.YDirectionEditField_2.RoundFractionalValues = 'on';
            app.YDirectionEditField_2.Value = 1;
            app.ZDirectionEditField_2Label = uilabel(app.VariableDensityTab);
            app.ZDirectionEditField_2Label.Text = 'Z Direction';
            app.ZDirectionEditField_2 = uieditfield(app.VariableDensityTab, 'numeric');
            app.ZDirectionEditField_2.Limits = [1 100];
            app.ZDirectionEditField_2.RoundFractionalValues = 'on';
            app.ZDirectionEditField_2.Value = 1;
            app.FunctionDropDown_2 = uidropdown(app.VariableDensityTab);
            app.FunctionDropDown_2.Items = {'Gyroid', 'Double Gyroid', 'Schwarz Primitive (SP)', 'Double SP', 'Schwarz Diamond (SD)', 'Double SD', 'IWP', 'FKS', 'FRD', 'Lidinoid', 'Neovius', 'Split P'};
            app.FunctionDropDown_2.Value = 'Gyroid';
            app.FunctionDropDown_2Label = uilabel(app.VariableDensityTab);
            app.FunctionDropDown_2Label.Text = 'Function';
            app.Switch_2 = uiswitch(app.VariableDensityTab, 'slider');
            app.Switch_2.Items = {'Sheet', 'Skeletal'};
            app.Switch_2.Value = 'Sheet';
            app.EditField_2 = uieditfield(app.VariableDensityTab, 'text');
            app.EditField_2.Value = 'Go!';
            app.RunningLamp_2 = uilamp(app.VariableDensityTab);
            app.PlotButton_2 = uibutton(app.VariableDensityTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.ExportButton_2 = uibutton(app.VariableDensityTab, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.DirectionDropDownLabel = uilabel(app.VariableDensityTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.VariableDensityTab);
            app.DirectionDropDown.Items = {'X', 'Y', 'Z'};
            app.DirectionDropDown.Value = 'X';
            app.MinimumSliderLabel = uilabel(app.VariableDensityTab);
            app.MinimumSliderLabel.Text = 'Minimum';
            app.MinimumSlider = uislider(app.VariableDensityTab);
            app.MinimumSlider.Value = 2;
            app.MaximumSliderLabel = uilabel(app.VariableDensityTab);
            app.MaximumSliderLabel.Text = 'Maximum';
            app.MaximumSlider = uislider(app.VariableDensityTab);
            app.MaximumSlider.Value = 90;
            app.DensityLabel = uilabel(app.VariableDensityTab);
            app.DensityLabel.Text = 'Density';
            app.CellSizeGradingTab = uitab(app.TabGroup);
            app.CellSizeGradingTab.Title = 'Cell Size Grading';
            app.Panel_3 = uipanel(app.CellSizeGradingTab);
            app.UIAxes_3 = uiaxes(app.Panel_3);
            title(app.UIAxes_3, 'Figure')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.CellParametersLabel_3 = uilabel(app.CellSizeGradingTab);
            app.CellParametersLabel_3.Text = 'Cell Parameters';
            app.StartingcellsEditFieldLabel = uilabel(app.CellSizeGradingTab);
            app.StartingcellsEditFieldLabel.Text = 'Starting cells';
            app.StartingcellsEditField = uieditfield(app.CellSizeGradingTab, 'numeric');
            app.StartingcellsEditField.Limits = [1 100];
            app.StartingcellsEditField.RoundFractionalValues = 'on';
            app.StartingcellsEditField.Value = 2;
            app.EndingcellsEditFieldLabel = uilabel(app.CellSizeGradingTab);
            app.EndingcellsEditFieldLabel.Text = 'Ending cells';
            app.EndingcellsEditField = uieditfield(app.CellSizeGradingTab, 'numeric');
            app.EndingcellsEditField.Limits = [1 100];
            app.EndingcellsEditField.RoundFractionalValues = 'on';
            app.EndingcellsEditField.Value = 4;
            app.LevelSetKnob_2 = uiknob(app.CellSizeGradingTab, 'continuous');
            app.LevelSetKnob_2.Limits = [-5 5];
            app.LevelSetKnob_2.Value = 0.3;
            app.LevelSetKnob_2Label = uilabel(app.CellSizeGradingTab);
            app.LevelSetKnob_2Label.Text = 'Level Set ';
            app.FunctionDropDown_3 = uidropdown(app.CellSizeGradingTab);
            app.FunctionDropDown_3.Items = {'Gyroid', 'Double Gyroid', 'Schwarz Primitive (SP)', 'Double SP', 'Schwarz Diamond (SD)', 'Double SD', 'IWP', 'FKS', 'FRD', 'Lidinoid', 'Neovius', 'Split P'};
            app.FunctionDropDown_3.Value = 'Gyroid';
            app.FunctionDropDown_3Label = uilabel(app.CellSizeGradingTab);
            app.FunctionDropDown_3Label.Text = 'Function';
            app.Switch_3 = uiswitch(app.CellSizeGradingTab, 'slider');
            app.Switch_3.Items = {'Sheet', 'Skeletal'};
            app.Switch_3.Value = 'Sheet';
            app.EditField_3 = uieditfield(app.CellSizeGradingTab, 'text');
            app.EditField_3.Value = 'Go!';
            app.RunningLamp_3 = uilamp(app.CellSizeGradingTab);
            app.PlotButton_3 = uibutton(app.CellSizeGradingTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.ExportButton_3 = uibutton(app.CellSizeGradingTab, 'push');
            app.ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_3Pushed, true);
            app.ExportButton_3.Text = 'Export';
            app.HybridizationTab = uitab(app.TabGroup);
            app.HybridizationTab.Title = 'Hybridization';
            app.Panel_4 = uipanel(app.HybridizationTab);
            app.UIAxes_4 = uiaxes(app.Panel_4);
            title(app.UIAxes_4, 'Figure')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.CellParameters1Label = uilabel(app.HybridizationTab);
            app.CellParameters1Label.Text = 'Cell Parameters 1';
            app.EditField_4 = uieditfield(app.HybridizationTab, 'text');
            app.EditField_4.Value = 'Go!';
            app.RunningLamp_4 = uilamp(app.HybridizationTab);
            app.YEditFieldLabel = uilabel(app.HybridizationTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.HybridizationTab, 'numeric');
            app.YEditField.Limits = [0 100];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.Value = 3;
            app.XEditField = uieditfield(app.HybridizationTab, 'numeric');
            app.XEditField.Limits = [0 100];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.Value = 3;
            app.XEditFieldLabel = uilabel(app.HybridizationTab);
            app.XEditFieldLabel.Text = 'X';
            app.ZEditField = uieditfield(app.HybridizationTab, 'numeric');
            app.ZEditField.Limits = [0 100];
            app.ZEditField.RoundFractionalValues = 'on';
            app.ZEditField.Value = 3;
            app.ZEditFieldLabel = uilabel(app.HybridizationTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.CellParameters2Label = uilabel(app.HybridizationTab);
            app.CellParameters2Label.Text = 'Cell Parameters 2';
            app.XEditField_2 = uieditfield(app.HybridizationTab, 'numeric');
            app.XEditField_2.Limits = [0 100];
            app.XEditField_2.RoundFractionalValues = 'on';
            app.XEditField_2.Value = 3;
            app.XEditField_2Label = uilabel(app.HybridizationTab);
            app.XEditField_2Label.Text = 'X';
            app.YEditField_2 = uieditfield(app.HybridizationTab, 'numeric');
            app.YEditField_2.Limits = [0 100];
            app.YEditField_2.RoundFractionalValues = 'on';
            app.YEditField_2.Value = 3;
            app.YEditField_2Label = uilabel(app.HybridizationTab);
            app.YEditField_2Label.Text = 'Y';
            app.ZEditField_2 = uieditfield(app.HybridizationTab, 'numeric');
            app.ZEditField_2.Limits = [0 100];
            app.ZEditField_2.RoundFractionalValues = 'on';
            app.ZEditField_2.Value = 3;
            app.ZEditField_2Label = uilabel(app.HybridizationTab);
            app.ZEditField_2Label.Text = 'Z';
            app.LevelSetKnob_3Label = uilabel(app.HybridizationTab);
            app.LevelSetKnob_3Label.Text = 'Level Set ';
            app.LevelSetKnob_3 = uiknob(app.HybridizationTab, 'continuous');
            app.LevelSetKnob_3.Limits = [-5 5];
            app.LevelSetKnob_3.Value = 0.3;
            app.Function1DropDownLabel = uilabel(app.HybridizationTab);
            app.Function1DropDownLabel.Text = 'Function 1';
            app.Function1DropDown = uidropdown(app.HybridizationTab);
            app.Function1DropDown.Items = {'Gyroid', 'Double Gyroid', 'Schwarz Primitive (SP)', 'Double SP', 'Schwarz Diamond (SD)', 'Double SD', 'IWP', 'FKS', 'FRD', 'Lidinoid', 'Neovius', 'Split P'};
            app.Function1DropDown.Value = 'Gyroid';
            app.Function2DropDownLabel = uilabel(app.HybridizationTab);
            app.Function2DropDownLabel.Text = 'Function 2';
            app.Function2DropDown = uidropdown(app.HybridizationTab);
            app.Function2DropDown.Items = {'Gyroid', 'Double Gyroid', 'Schwarz Primitive (SP)', 'Double SP', 'Schwarz Diamond (SD)', 'Double SD', 'IWP', 'FKS', 'FRD', 'Lidinoid', 'Neovius', 'Split P'};
            app.Function2DropDown.Value = 'Schwarz Primitive (SP)';
            app.DirectionDropDown_2Label = uilabel(app.HybridizationTab);
            app.DirectionDropDown_2Label.Text = 'Direction';
            app.DirectionDropDown_2 = uidropdown(app.HybridizationTab);
            app.DirectionDropDown_2.Items = {'X', 'Y', 'Z'};
            app.DirectionDropDown_2.Value = 'X';
            app.SigmoidFunctionkEditFieldLabel = uilabel(app.HybridizationTab);
            app.SigmoidFunctionkEditFieldLabel.Text = 'Sigmoid Function (k)';
            app.SigmoidFunctionkEditField = uieditfield(app.HybridizationTab, 'numeric');
            app.SigmoidFunctionkEditField.Value = 3;
            app.Switch_4 = uiswitch(app.HybridizationTab, 'slider');
            app.Switch_4.Items = {'Sheet', 'Skeletal'};
            app.Switch_4.Value = 'Sheet';
            app.PlotButton_4 = uibutton(app.HybridizationTab, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.ExportButton_4 = uibutton(app.HybridizationTab, 'push');
            app.ExportButton_4.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_4Pushed, true);
            app.ExportButton_4.Text = 'Export';
            app.HierarchicalTab = uitab(app.TabGroup);
            app.HierarchicalTab.Title = 'Hierarchical';
            app.Panel_5 = uipanel(app.HierarchicalTab);
            app.UIAxes_5 = uiaxes(app.Panel_5);
            title(app.UIAxes_5, 'Figure')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.CellParameters1Label_2 = uilabel(app.HierarchicalTab);
            app.CellParameters1Label_2.Text = 'Cell Parameters 1';
            app.EditField_5 = uieditfield(app.HierarchicalTab, 'text');
            app.EditField_5.Value = 'Go!';
            app.RunningLamp_5 = uilamp(app.HierarchicalTab);
            app.YEditField_3Label = uilabel(app.HierarchicalTab);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.HierarchicalTab, 'numeric');
            app.YEditField_3.Limits = [0 100];
            app.YEditField_3.RoundFractionalValues = 'on';
            app.YEditField_3.Value = 6;
            app.XEditField_3 = uieditfield(app.HierarchicalTab, 'numeric');
            app.XEditField_3.Limits = [0 100];
            app.XEditField_3.RoundFractionalValues = 'on';
            app.XEditField_3.Value = 6;
            app.XEditField_3Label = uilabel(app.HierarchicalTab);
            app.XEditField_3Label.Text = 'X';
            app.ZEditField_3 = uieditfield(app.HierarchicalTab, 'numeric');
            app.ZEditField_3.Limits = [0 100];
            app.ZEditField_3.RoundFractionalValues = 'on';
            app.ZEditField_3.Value = 6;
            app.ZEditField_3Label = uilabel(app.HierarchicalTab);
            app.ZEditField_3Label.Text = 'Z';
            app.CellParameters2Label_2 = uilabel(app.HierarchicalTab);
            app.CellParameters2Label_2.Text = 'Cell Parameters 2';
            app.XEditField_4 = uieditfield(app.HierarchicalTab, 'numeric');
            app.XEditField_4.Limits = [0 100];
            app.XEditField_4.RoundFractionalValues = 'on';
            app.XEditField_4.Value = 1;
            app.XEditField_4Label = uilabel(app.HierarchicalTab);
            app.XEditField_4Label.Text = 'X';
            app.YEditField_4 = uieditfield(app.HierarchicalTab, 'numeric');
            app.YEditField_4.Limits = [0 100];
            app.YEditField_4.RoundFractionalValues = 'on';
            app.YEditField_4.Value = 1;
            app.YEditField_4Label = uilabel(app.HierarchicalTab);
            app.YEditField_4Label.Text = 'Y';
            app.ZEditField_4 = uieditfield(app.HierarchicalTab, 'numeric');
            app.ZEditField_4.Limits = [0 100];
            app.ZEditField_4.RoundFractionalValues = 'on';
            app.ZEditField_4.Value = 1;
            app.ZEditField_4Label = uilabel(app.HierarchicalTab);
            app.ZEditField_4Label.Text = 'Z';
            app.Function1DropDown_2Label = uilabel(app.HierarchicalTab);
            app.Function1DropDown_2Label.Text = 'Function 1';
            app.Function1DropDown_2 = uidropdown(app.HierarchicalTab);
            app.Function1DropDown_2.Items = {'Gyroid', 'Double Gyroid', 'Schwarz Primitive (SP)', 'Double SP', 'Schwarz Diamond (SD)', 'Double SD', 'IWP', 'FKS', 'FRD', 'Lidinoid', 'Neovius', 'Split P'};
            app.Function1DropDown_2.Value = 'Gyroid';
            app.Function2DropDown_2Label = uilabel(app.HierarchicalTab);
            app.Function2DropDown_2Label.Text = 'Function 2';
            app.Function2DropDown_2 = uidropdown(app.HierarchicalTab);
            app.Function2DropDown_2.Items = {'Gyroid', 'Double Gyroid', 'Schwarz Primitive (SP)', 'Double SP', 'Schwarz Diamond (SD)', 'Double SD', 'IWP', 'FKS', 'FRD', 'Lidinoid', 'Neovius', 'Split P'};
            app.Function2DropDown_2.Value = 'Schwarz Primitive (SP)';
            app.Switch_5 = uiswitch(app.HierarchicalTab, 'slider');
            app.Switch_5.Items = {'Sheet', 'Skeletal'};
            app.Switch_5.Value = 'Sheet';
            app.PlotButton_5 = uibutton(app.HierarchicalTab, 'push');
            app.PlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_5Pushed, true);
            app.PlotButton_5.Text = 'Plot';
            app.ExportButton_5 = uibutton(app.HierarchicalTab, 'push');
            app.ExportButton_5.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_5Pushed, true);
            app.ExportButton_5.Text = 'Export';
            app.LevelSetFunction1KnobLabel = uilabel(app.HierarchicalTab);
            app.LevelSetFunction1KnobLabel.Text = 'Level Set Function 1 ';
            app.LevelSetFunction1Knob = uiknob(app.HierarchicalTab, 'continuous');
            app.LevelSetFunction1Knob.Limits = [-5 5];
            app.LevelSetFunction1Knob.Value = 0.3;
            app.LevelSetFunction2KnobLabel = uilabel(app.HierarchicalTab);
            app.LevelSetFunction2KnobLabel.Text = 'Level Set Function  2';
            app.LevelSetFunction2Knob = uiknob(app.HierarchicalTab, 'continuous');
            app.LevelSetFunction2Knob.Limits = [-5 5];
            app.LevelSetFunction2Knob.Value = 0.3;
            app.Lattice_KarakLabel = uilabel(app.UIFigure);
            app.Lattice_KarakLabel.Text = 'Lattice _Karak';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lattice_Karak

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end